classdef uidropdownmultiselect < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        DropDown    matlab.ui.control.DropDown
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Clicked % Drop-down menu clicked
        DropDownOpening % Drop-down menu opening
        ValueChanged % Drop-down selection changed
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        Figure {mustBeA(Figure, {'matlab.ui.Figure', 'matlab.graphics.GraphicsPlaceholder'})} = gobjects(0); % The parent figure containing this component
        ItemsSelectionMask (1,:) logical = false(1,0); % Selection mask on comp.Items
        MenuOpened (1,1) logical = false; % Whether the dropdown menu is current open
        ValueInternal (1,:) {mustBeA(ValueInternal, 'cell')} = {}; % The internal value inferred via ItemsSelectionMask
    end
    
    properties (AbortSet, Access = public, GetObservable, Hidden, SetObservable)
        IgnoreUpdate (1,1) logical = true; % When this is True, the update() function will be skipped entirely
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Default (1,1) logical = false; % The default selection state when new options are added
        Enable (1,1) logical = true; % Enable state for dropdown
        FontAngle {mustBeTextScalar(FontAngle), mustBeMember(FontAngle, {'normal', 'italic'})} = 'normal'; % Font angle for dropdown text
        FontColor % Font color for dropdown text. Must pass validatecolor()
        FontName {mustBeTextScalar(FontName)} = 'Helvetica'; % Font name for dropdown text
        FontSize (1,1) double = 12; % Font size for dropdown text
        FontWeight {mustBeTextScalar(FontWeight), mustBeMember(FontWeight, {'normal', 'bold'})} = 'normal'; % Font weight for dropdown text
        Items (1,:) {mustBeText(Items)} = {''}; % Drop-down menu items text (required)
        ItemsData (1,:) = {}; % Data associated with Items. This is the value returned in Value, will fallback to Items if not set.
        Placeholder {mustBeTextScalar(Placeholder)} = 'Select some options...'; % Placeholder text on dropdown when Items is empty
        Tooltip (1,:) {mustBeText(Tooltip)} = ''; % Tooltip for dropdown
        Value (1,:) {mustBeA(Value, 'cell')} = {}; % Selected values. Data returned is ItemsData unless empty, in which case will fallback to Items
    end
    
    properties (Access = private, Constant)
        styledCheckbox (1,1) {mustBeA(styledCheckbox, "matlab.ui.style.Style")} = uistyle("Icon","private/square.svg","IconAlignment","leftmargin"); % uistyle for non-checked elements
        styledCheckedBox (1,1) {mustBeA(styledCheckedBox, "matlab.ui.style.Style")} = uistyle("Icon","private/square-check-big.svg","IconAlignment","leftmargin");; % uistyle for checked elements
    end
    
    
    properties(Access=private,Transient,NonCopyable)
        DoFullUpdate (1,1) logical = false
    end

    
    methods (Access = private)
        
        function handleFigureButtonDown(comp, ~, ~)
            % ButtonDown event on Figure is only registered when the click is outside of the dropdown and its menu
            comp.MenuOpened = false;
            setTriggerLabel(comp);

        end

        function fakeSpacebar(~)
            import java.awt.Robot;
            import java.awt.event.*;
            simKey = Robot;
            simKey.keyPress(KeyEvent.VK_SPACE); % Simulate pressing Spacebar to keep the dropdown menu open
            simKey.keyRelease(KeyEvent.VK_SPACE);
        end

        function updateValueViaMask(comp)
            value = comp.Items;
            if ~isempty(comp.ItemsData)
                for i = 1:min(length(comp.Items), length(comp.ItemsData))
                    if ~iscell(comp.ItemsData(i))
                        if ~isempty(comp.ItemsData(i))
                            value(i) = cell(comp.ItemsData(i));
                        end
                    else
                        if ~isempty(comp.ItemsData{i})
                            value(i) = comp.ItemsData(i);
                        end
                    end
                end
            end

            % Check if all comp.Value items can be found in either comp.Items or comp.ItemsData
            % if so, we can simply follow the merged value(mask)
            mixed = [comp.Items, comp.ItemsData];
            % This is a bit insane but not sure what is the better way to check elements of a heterogeneous cell array
            % in another het cell array (since ItemsData can be anything wrapped in cell{})
            % Hashing the variable data is probably best, so maybe DataHash() from FEX?
            % Maybe using the internal hashing of MATLAB dictionary is faster?
            % We dont care about value, only use the key of dictionary for lookup, similar to typical HashSet, so use logical type for lower memory footprint
            dict = configureDictionary('cell', 'logical');
            for i = 1:length(mixed)
                dict(mixed(i)) = false;
            end

            allvalueexist = all(arrayfun(@(x) isKey(dict, x), comp.Value));
            if ~allvalueexist
                error("uidropdownmultiselect:setupDropDown:InvalidValue", "comp.Value, when set, must be member of comp.ItemsData (fallback to comp.Items if ItemsData isempty).")
            end

            manualValueUpdate = ~isequal(comp.Value, comp.ValueInternal);
            if manualValueUpdate
                % If value was set manually, find the corresponding index to set in ItemsSelectionMask
                indices = cellfun(@(target) find(cellfun(@(collection) isequal(collection, target), mixed), 1), comp.Value); % nested cellfun, are you having fun?
                % Regardless of the matches in Items or ItemsData, since Items are required and ItemsData is in-order with Items (even if of different length),
                % we can modulo the index (with wrapping)
                whydoesmatlabindexingstartsfrom1justtomakemoduloindexingsucks = @(x, N) (1 + mod(x-1, N)); % mod but wrap around so that k*N % N = N
                indices = whydoesmatlabindexingstartsfrom1justtomakemoduloindexingsucks(indices, length(comp.Items));
                indices = unique(indices); % just in case it matches to the same index (probably rare, though, unless the user doing something weird)
                mask = false([1, length(comp.ItemsSelectionMask)]); % should also be the length of Items
                mask(indices) = true;
                comp.ItemsSelectionMask = mask;
            end
            comp.ValueInternal = value(comp.ItemsSelectionMask);
            comp.Value = value(comp.ItemsSelectionMask);
            

            if manualValueUpdate
                if length(comp.ItemsSelectionMask) ~= length(comp.Items)
                    comp.ItemsSelectionMask = repmat(comp.Default, [1, length(comp.Items)]);
                end
                setTriggerLabel(comp);

                assert(length(comp.DropDown.Items) == length(comp.Items)+1);
                disabledStyle = uistyle("FontColor", [0.35, 0.35, 0.35]);
                addStyle(comp.DropDown, disabledStyle, "item", 1);
                for i = 1:length(comp.Items)
                    if comp.ItemsSelectionMask(i)
                        addStyle(comp.DropDown, comp.styledCheckedBox, "item", i+1);
                    else
                        addStyle(comp.DropDown, comp.styledCheckbox, "item", i+1);
                    end
                end
                drawnow;
            end
            notify(comp, 'ValueChanged');
            drawnow;
        end

        function setupDropDown(comp)
            % Setting up the dropdown and props with proper style and data
            if isempty(comp.Items)
                comp.DropDown.Items = {};

                comp.ItemsSelectionMask = false(1,0);
                comp.Value = {};
                updateValueViaMask(comp);
                return;
            end


            if length(comp.ItemsSelectionMask) ~= length(comp.Items)
                if isempty(comp.ItemsSelectionMask)
                    comp.ItemsSelectionMask = comp.Default;
                end
                comp.ItemsSelectionMask = resize(comp.ItemsSelectionMask, length(comp.Items), FillValue=comp.Default);
                % comp.ItemsSelectionMask = repmat(comp.Default, [1, length(comp.Items)]);
                % ;;comp.ItemsSelectionMask
            end


            updateValueViaMask(comp);
            setTriggerLabel(comp);

            % assert(length(comp.DropDown.Items) == length(comp.Items)+1)
            if length(comp.DropDown.Items) ~= (length(comp.Items)+1)
                return
            end

            disabledStyle = uistyle("FontColor", [0.35, 0.35, 0.35]);
            addStyle(comp.DropDown, disabledStyle, "item", 1);

            for i = 1:length(comp.Items)
                if comp.ItemsSelectionMask(i)
                    addStyle(comp.DropDown, comp.styledCheckedBox, "item", i+1);
                else
                    addStyle(comp.DropDown, comp.styledCheckbox, "item", i+1);
                end
            end
            drawnow;
        end


        function setTriggerLabel(comp)
            count = sum(comp.ItemsSelectionMask);
            % This is the trigger item that is purely for UI and only exists on comp.DropDown
            % comp.ItemsSelectionMask operate on comp.Items so it should be independent of this.
            comp.DropDown.Items = [cellstr(comp.Placeholder); reshape(cellstr(comp.Items), [], 1)];

            if count > 0 && (length(comp.DropDown.Items) == length(comp.Items)+1) && ~comp.MenuOpened
                str = string(comp.Items(comp.ItemsSelectionMask));
                str = strjoin(str, ", ");
                str = sprintf("(%d) %s", count, str);
                comp.DropDown.Items(1) = cellstr(str);
            elseif comp.MenuOpened
                str = sprintf("(Selected: %d)", count);
                comp.DropDown.Items(1) = cellstr(str);
            end

            comp.DropDown.Value = comp.DropDown.Items{1};
            drawnow;
        end


        function runFullUpdate(comp)
            if ~comp.DoFullUpdate
                return
            end

            comp.Items = reshape(cellstr(comp.Items), [], 1);
            comp.ItemsData = reshape(comp.ItemsData, [], 1);
            if ~isempty(comp.Items)
                emptyelem = cellfun(@isempty, comp.Items);
                comp.Items(emptyelem) = [];
            end
            if ~isempty(comp.Items)
                try
                    comp.setupDropDown();
                catch ME
                    warning(getReport(ME))
                end
            end

            setTriggerLabel(comp);

            %REMEMBER TO SET THE DoFullUpdate FLAG BACK TO FALSE IN THE CALLER FUNCTION!

        end
    end
    
    methods
        
        function set.Items(comp, val)
            bool = ~isequal(comp.Items, val);
            comp.Items = cellstr(val);
            comp.DoFullUpdate = bool;
        end


        function set.ItemsData(comp, val)
            bool = ~isequal(comp.ItemsData, val);
            comp.ItemsData = val;
            comp.DoFullUpdate = bool;
        end

    end


    methods (Access=public)

        function reloadSelectionDropDown(comp)
            comp.IgnoreUpdate = true;
            runFullUpdate(comp);
            comp.IgnoreUpdate = false;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            % PostSetup often breaks thing when loaded in AppDesigner vs. on its own, so we should
            % just wrap it in try-catch
            try
                f = ancestor(comp, 'figure');
                if isempty(f) || ~isvalid(f)
                    comp.IgnoreUpdate = true;
                    return
                else
                    comp.IgnoreUpdate = false;
                end

                comp.FontColor = comp.DropDown.FontColor;
                
                comp.Figure = f;
                callback = @(src, event) comp.handleFigureButtonDown(src, event);
                addlistener(comp.Figure, 'WindowMousePress', callback);
            catch ME
                warning("uidropdownmultiselect:postSetupFcn", "Error running the postSetup routine:\n%s", getReport(ME));
            end
        end

        % Clicked callback: DropDown
        function DropDownClicked(comp, event)
            item = event.InteractionInformation.Item - 1; % subtract the trigger item that is pinned to top
            if isempty(item)
                setTriggerLabel(comp);
                % if comp.MenuOpened
                %     comp.MenuOpened = false;
                % end
                return
            elseif item <= 0
                % Items should be starting from 1 after the first line already -1
                % If still <= 0, the user must have clicked on the trigger item label
                % We can simply ignore this interaction
                focus(comp.DropDown);
                if ~isempty(item)
                    comp.fakeSpacebar;
                    comp.MenuOpened = true;
                    setTriggerLabel(comp);
                end
                notify(comp, 'Clicked');
                return
            end

            assert(item <= length(comp.ItemsSelectionMask), "`item` that is the index of selected Items in comp.DropDown minus 1 should be less than or equal to the selection mask. Something really went wrong.");
            comp.ItemsSelectionMask(item) = ~comp.ItemsSelectionMask(item);
            if comp.ItemsSelectionMask(item)
                addStyle(comp.DropDown,comp.styledCheckedBox,"item",item+1);
            else
                addStyle(comp.DropDown,comp.styledCheckbox,"item",item+1);
            end
            updateValueViaMask(comp); % This function notify for value changed as well
            setTriggerLabel(comp);

            focus(comp.DropDown);
            if ~isempty(item)
                comp.fakeSpacebar;
                comp.MenuOpened = true;
            end

            
            % To match the built-in dropdown, notify value changed first (if any) before clicked
            notify(comp, 'Clicked');
        end

        % Drop down opening function: DropDown
        function DropDownCompOpening(comp, event)
            setTriggerLabel(comp);
            comp.MenuOpened = true; % The order here is significant! Do not change
            notify(comp, 'DropDownOpening');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            try


            if comp.IgnoreUpdate
                return
            end
            
            if ~isvalid(comp.Figure)
                f = ancestor(comp, 'figure');
                if isempty(f) || ~isvalid(f)
                    comp.Figure = gobjects(0);
                    return
                end
                comp.Figure = f;
            end


            %% Cosmetics
            comp.DropDown.Placeholder = comp.Placeholder;
            comp.DropDown.FontSize = comp.FontSize;
            comp.DropDown.FontWeight = comp.FontWeight;
            comp.DropDown.FontAngle = comp.FontAngle;
            tt = cellstr(comp.Tooltip);
            comp.DropDown.Tooltip = tt{1};

            if ~isequal(comp.FontColor, comp.DropDown.FontColor)
                try
                    comp.FontColor = validatecolor(comp.FontColor);
                    comp.DropDown.FontColor = comp.FontColor;
                catch ME
                    comp.FontColor = comp.DropDown.FontColor;
                    warning("uidropdownmultiselect:update:InvalidFontColor", "FontColor must be a single color value that can be parsed by validatecolor()");
                end
            end

            if ~isequal(comp.BackgroundColor, comp.DropDown.BackgroundColor)
                try
                    comp.BackgroundColor = validatecolor(comp.BackgroundColor);
                    comp.DropDown.BackgroundColor = comp.BackgroundColor;
                    comp.GridLayout.BackgroundColor = comp.BackgroundColor;
                catch ME
                    comp.BackgroundColor = comp.DropDown.BackgroundColor;
                    warning("uidropdownmultiselect:update:InvalidBackgroundColor", "BackgroundColor must be a single color value that can be parsed by validatecolor()");
                end
            end

            if ~isequal(comp.Enable, strcmpi(comp.DropDown.Enable, 'on'))
                try
                    comp.DropDown.Enable = matlab.lang.OnOffSwitchState(comp.Enable);
                catch ME
                    comp.Enable = strcmpi(comp.DropDown.Enable, 'on');
                    warning("uidropdownmultiselect:update:InvalidEnable", "Enable must be a logical true/false value.");
                end
            end

            if ~isequal(comp.FontName, comp.DropDown.FontName)
                try
                    comp.DropDown.FontName = comp.FontName;
                catch ME
                    comp.FontName = comp.DropDown.FontName;
                    warning("uidropdownmultiselect:update:InvalidFontName", "FontName must be a valid font name.");
                end
            end


            %% Data things

            if ~isequal(comp.Value, comp.ValueInternal)
                comp.updateValueViaMask();
            end

            if comp.DoFullUpdate
                try
                    comp.IgnoreUpdate = true;
                    comp.runFullUpdate();
                    comp.IgnoreUpdate = false;
                catch ME
                    warning("uidropdownmultiselect:update:RunFullUpdate", 'Something went wrong while attempt to run full component update for dropdown:\n%s', getReport(ME))
                end
                comp.DoFullUpdate = false;
            end


            catch ME
                warning(getReport(ME))
                comp.IgnoreUpdate = true; % block further update attempts until user manually set IgnoreUpdate to false again
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 39];
            comp.GridLayout = uigridlayout(comp);
            comp.DropDown = uidropdown(comp.GridLayout);
            comp.DropDown.Items = {};
            comp.DropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownCompOpening, true);
            comp.DropDown.ClickedFcn = matlab.apps.createCallbackFcn(comp, @DropDownClicked, true);
            comp.DropDown.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end