classdef FolderSelectorWithDropdown < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        CompGridLayout                 matlab.ui.container.GridLayout
        BackgroundPanel                matlab.ui.container.Panel
        BackgroundGrid                 matlab.ui.container.GridLayout
        SelectPathPanel                matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        FooterGridLayout               matlab.ui.container.GridLayout
        DropdownPanel                  matlab.ui.container.Panel
        DropdownGridLayout             matlab.ui.container.GridLayout
        DropdownBarWithSortGridLayout  matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        DropdownSettingButton          matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DropdownItemsCounterLabel      matlab.ui.control.Label
        SelectContentLabel             matlab.ui.control.Label
        SeletedPathTextArea            matlab.ui.control.TextArea
        SelectPathButton               matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ContentDropDownValueChanged
        ContentRefreshButtonPushed
        ParentFolderUpdated % The parent folder was re-selected, same as before or not
    end

    properties (Access = private)
        AppFigureValid (1,1) logical = false; % Whether AppFigure property is a valid Figure
        SkipUpdate (1,1) logical = false; % This value, if true, will skip running the update() function of the component
        TooltipText char % Display as tooltip when hover over the file selector dropzone
        cm % Context menu manager
        lastSelectedDir char {mustBeFolder(lastSelectedDir)} = pwd(); % Hold the path to the last selected directory
    end
    
    properties (Access = protected, Constant, Hidden)
        AppLoadLib__internal = import_lib();
    end
    
    properties (Access = public)
        AppFigure % The parent app/figure that this component is a child of
        BorderColor (1,3) double {mustBeReal(BorderColor), mustBeLessThanOrEqual(BorderColor, 1), mustBeGreaterThanOrEqual(BorderColor, 0)} = [0.65 0.65 0.65]; % Border color around the component
        BorderSize (1,1) double {mustBeReal(BorderSize), mustBePositive(BorderSize), mustBeFinite(BorderSize)} = 1; % Border width around the component, set to 0 for no border
        ChildFilterType char {mustBeMember(ChildFilterType, {'file', 'folder', 'any'})} = 'folder'; % Which sub-content within the parent path to display in dropdown. Must be 'file', 'folder', or 'any'.
        DropDownLabel char = 'Select Content'; % The label for the dropdown menu
        DropDownPlaceholder char {mustBeText(DropDownPlaceholder)} % The placeholder text inside of the dropdown menu when it's empty
        DropdownItems % The items available in the dropdown menu. As a Nx2 cell array: 1st column is the item's label, 2nd column is the corresponding item's data struct as returned by dir()
        DropdownItemsFilterFcn % A function handle to run after the parent folder is selected and the folder content is parsed, filtered by ChildFilterType (file, folder, or any). You can add custom logic here to filter down what is shown in the dropdown menu by updating the DropdownItems property.
        DropdownSortAscend (1,1) logical = true; % Direction to sort the Items in Dropdown. Must be either true (for ascend) or false (for descend)
        Enabled (1,1) logical = true;
        IsMouseover (1,1) logical {mustBeNonempty(IsMouseover)} = false; % Whether the cursor is currently on top of this component, must be properly set by the parent figure/app.
        PanelFontWeight char {mustBeMember(PanelFontWeight, {'normal', 'bold'})} = 'normal'; % Font weight of Panel Text
        PanelTitle char = 'Select Path'; % Set the title of the path selector dropzone box
        PanelTitleFontSize (1,1) double {mustBeReal(PanelTitleFontSize), mustBePositive(PanelTitleFontSize)} = 12; % Font size of Panel Text
        PanelTitlePosition char {mustBeMember(PanelTitlePosition, {'lefttop', 'centertop', 'righttop'})} = 'lefttop'; % Panel Title Text Alignment
        Placeholder {mustBeText(Placeholder)} = "Select Path"; % Placeholder text to display when the SelectedParent result is empty
        SelectedContent % The content selected in the dropdown menu
        SelectedParent % The user-selected parent folder
        ValidateSelectedParentFcn % A function handle to run after the user select a parent folder.
    end
    
    
    methods (Access = private)

        function sortDropdownItems(comp, src, ~, ascending)
            if nargin < 4
                ascending = True;
            end

            items = comp.DropdownItems;

            switch ascending
                case true
                    items = sortrows(items, 1, 'ascend');
                case false
                    items = sortrows(items, 1, 'descend');
            end

            comp.DropdownSortAscend = ascending;
            comp.DropdownItems = items;

            if class(src) == "matlab.ui.container.Menu"
                filterContextMenuChildren = comp.cm.DropdownFilterContextMenu.Children;
                for i=1:length(filterContextMenuChildren)
                    filterContextMenuChildren(i).Checked = 'off';
                end
                src.Checked = 'on';
            end
        end
        

        function getParentFolderContent(comp)
            if isempty(comp.SelectedParent)
                comp.DropdownItems = {};
                comp.SelectedContent = {};
                return
            end

            selectedPath = comp.SelectedParent;
            content = dir(string(selectedPath));
            content = content(~ismember({content.name}, {'.', '..'}));
            % Filter for the type indicated
            switch comp.ChildFilterType
                case 'file'
                    content = content(arrayfun(@(x) ~x.isdir, content));
                case 'folder'
                    content = content(arrayfun(@(x) x.isdir, content));
            end

            if isempty(comp.DropdownItems)
                comp.DropdownItems = cell(0,2);
            end

            names = cellstr({content.name});
            data = content(:);

            items = [names(:), num2cell(data)];

            comp.DropdownItems = items;
            handleCallbackFcn(comp, comp.DropdownItemsFilterFcn);

            sortDropdownItems(comp, [], [], comp.DropdownSortAscend);

        end


        function dnd_ui_dropped(comp, ~, data)
            % Get parent figure for focus/alert
            if comp.AppFigureValid
                parentfig = comp.AppFigure;
            else
                parentfig = ancestor(comp.CompGridLayout, 'figure');
            end
            
            selectedPath = filterSelectedPaths(data.names, type='folder', allowMultiple=false);
            if isempty(selectedPath)
                 % Error notification
                uialert(parentfig, 'You must select a valid path to a folder', 'Invalid Folder Path');
                return
            end

            % Ensure selectedPath is text scalar
            % allowMultiple is already false, so we can safely just convert it to char
            selectedPath = char(string(selectedPath));


            comp.SelectedParent = selectedPath;
            handleCallbackFcn(comp, comp.ValidateSelectedParentFcn);

            % The user might update the SelectedParent prop
            selectedPath = comp.SelectedParent;
            if isempty(selectedPath) ...
               || ~(isstring(selectedPath)||ischar(selectedPath)||iscellstr(selectedPath)) ...
               || ~isfolder(selectedPath)
                return
            end

            % If there is still a valid path here, parse the folder content
            getParentFolderContent(comp);
            notify(comp, 'ParentFolderUpdated');
        end


        function handleCallbackFcn(comp, callbackFcnProperty)
            if ~isempty(callbackFcnProperty) && iscell(callbackFcnProperty)
                validateFcn = callbackFcnProperty{1};
                if isscalar(callbackFcnProperty)
                    validateFcn(comp)
                else
                    validateFcn(comp, callbackFcnProperty{2:end})
                end
            end
        end


        function validateUserCallbackFunction(comp, callbackFcnPropertyValue, callbackFcnPropertyName)
            if ~isprop(comp, callbackFcnPropertyName)
                error("'callbackFcnPropertyName' must be a valid property of this Component");
            end

            if ~iscell(callbackFcnPropertyValue)
                comp.(callbackFcnPropertyName) = {callbackFcnPropertyValue};
            end
            callbackFcnPropertyValue = comp.(callbackFcnPropertyName);
            
            if ~isempty(callbackFcnPropertyValue) && ~isa(callbackFcnPropertyValue{1}, 'function_handle')
                comp.(callbackFcnPropertyName) = {};
            end
            callbackFcnPropertyValue = comp.(callbackFcnPropertyName);

            if ~isempty(callbackFcnPropertyValue)
                fncArgCount = nargin(callbackFcnPropertyValue{1});
                if fncArgCount < 0
                    fncArgCount = abs(fncArgCount); % for now this seem to work with most case using nargin, if breaks in the future, update this!
                end
            else
                return
            end

            % comp.(callbackFcnPropertyName) is a cell array whose first
            % element is a function handle, and the remaining elements are
            % the extra arguments for the function

            % Note that the program will add 'comp' as the first argument,
            % so the number of arguments passed in by the user must be
            % length(callbackFcnPropertyValue(2:end)) + 1, or just length(callbackFcnPropertyValue)

            if fncArgCount > length(callbackFcnPropertyValue)
                error(strcat("Not enough input arguments for function ", string(callbackFcnPropertyName)))
            elseif fncArgCount < length(comp.ValidateSelectedParentFcn)
                error(strcat("Too many input arguments for function ", string(callbackFcnPropertyName)))
            end
        end


    end
    
    methods (Access = public)
        
        function refreshDropdown(comp)
            RefreshButtonPushed(comp, []);
        end

        function setSelection(comp, idx)
            % Set the dropdown selected value to the corresponding item
            % with the index `idx`
            
            % Make sure that the dropdown holds the lastest state of
            % DropdownItems
            if ~isempty(comp.DropdownItems) && size(comp.DropdownItems, 2) == 2
                comp.DropDown.Items = comp.DropdownItems(:,1)';
                comp.DropDown.ItemsData = comp.DropdownItems(:,2)';
                comp.DropdownSettingButton.Enable = "on";
            else
                comp.DropDown.Items = {};
                comp.DropDown.ItemsData = {};
                comp.DropdownSettingButton.Enable = "off";
            end

            if idx > size(comp.DropDown.Items, 2) || idx < 1
                return
            end

            comp.DropDown.Value = comp.DropDown.ItemsData{idx};
            DropDownValueChanged(comp, []);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            try
                figureRef = ancestor(comp.CompGridLayout, 'figure');

                % Make a filter context menu for tuning the dropdown
                comp.cm.DropdownFilterContextMenu = uicontextmenu(figureRef);
                comp.cm.figureRef = figureRef;

                comp.cm.SortFileNameAsc = uimenu(comp.cm.DropdownFilterContextMenu);
                comp.cm.SortFileNameAsc.Text = 'Sort: Name Ascending';
                comp.cm.SortFileNameAsc.MenuSelectedFcn = {@comp.sortDropdownItems, true};

                comp.cm.SortFileNameDesc = uimenu(comp.cm.DropdownFilterContextMenu);
                comp.cm.SortFileNameDesc.Text = 'Sort: Name Descending';
                comp.cm.SortFileNameDesc.MenuSelectedFcn = {@comp.sortDropdownItems, false};

                set(figureRef, 'uicontextmenu', comp.cm.DropdownFilterContextMenu);
            catch
                return
            end


            % Initializing the drag and drop zone
            % MUST wrap in try-catch since opening in AppDesigner will
            % throw an error
            try
                DnD_uifigure(comp.CompGridLayout, @(o,dat) dnd_ui_dropped(comp, o, dat));
            catch
                return
            end
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(comp, event)
            % Get parent figure for focus/alert
            if comp.AppFigureValid
                parentfig = comp.AppFigure;
            else
                parentfig = ancestor(comp.CompGridLayout, 'figure');
            end
            
            selectedPath = uigetdir(comp.lastSelectedDir, comp.PanelTitle);
            figure(parentfig); % reset the focus to the current app
            if ~selectedPath
                return
            end

            selectedPath = cellstr(selectedPath);
            comp.lastSelectedDir = fileparts(fullfile(selectedPath));

            if any(cellfun(@isempty, selectedPath))
                return
            end

            selectedPath = filterSelectedPaths(selectedPath, type='folder', allowMultiple=false);
            
            if isempty(selectedPath)
                % Error notification
                uialert(parentfig, 'You must select a valid path to a folder', 'Invalid Folder Path');
                return
            end

            % Ensure selectedPath is text scalar
            % allowMultiple is already false, so we can safely just convert it to char
            selectedPath = char(string(selectedPath'));

            comp.SelectedParent = selectedPath;
            handleCallbackFcn(comp, comp.ValidateSelectedParentFcn);
            
            % The user might update the SelectedParent prop
            selectedPath = comp.SelectedParent;
            if isempty(selectedPath) ...
               || ~(isstring(selectedPath)||ischar(selectedPath)||iscellstr(selectedPath)) ...
               || ~isfolder(selectedPath)
                return
            end

            % If there is still a valid path here, parse the folder content
            getParentFolderContent(comp);
            notify(comp, 'ParentFolderUpdated');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            comp.SelectedContent = value;
            

            % At the very end, trigger the event
            notify(comp, 'ContentDropDownValueChanged');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(comp, event)
            % Make sure the parent path is still valid
            selectedPath = comp.SelectedParent;
            if isempty(selectedPath) ...
               || ~(isstring(selectedPath)||ischar(selectedPath)||iscellstr(selectedPath)) ...
               || ~isfolder(selectedPath)
                return
            end

            % Check the folder content again
            getParentFolderContent(comp);
            notify(comp, 'ParentFolderUpdated');


            % At the very end, trigger the event
            notify(comp, 'ContentRefreshButtonPushed');
        end

        % Button pushed function: DropdownSettingButton
        function DropdownSettingButtonPushed(comp, event)
            pos = getpixelposition(event.Source, true);
            pos = [ceil(pos(1) + pos(3)/2), ceil(pos(2) - pos(4)/2)];
            open(comp.cm.DropdownFilterContextMenu, pos);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            if comp.SkipUpdate
                return
            end
            
            % Use this function to update the underlying components

            % Check if the parent fig is valid
            if class(comp.AppFigure) == "matlab.ui.Figure" && isvalid(comp.AppFigure)
                comp.AppFigureValid = true;
            else
                comp.AppFigureValid = false;
            end

            if comp.Enabled
                comp.BackgroundPanel.Enable = 'on';
            else
                comp.BackgroundPanel.Enable = 'off';
            end

            % Validate user's extra validation functions
            validateUserCallbackFunction(comp, comp.ValidateSelectedParentFcn, 'ValidateSelectedParentFcn');
            validateUserCallbackFunction(comp, comp.DropdownItemsFilterFcn, 'DropdownItemsFilterFcn');


            % Border
            comp.BackgroundPanel.BorderColor = comp.BorderColor;
            comp.SelectPathPanel.BorderColor = comp.BorderColor;
            comp.BackgroundPanel.BorderWidth = comp.BorderSize;

            % Panel
            comp.SelectPathPanel.FontWeight = comp.PanelFontWeight;
            comp.SelectPathPanel.Title = comp.PanelTitle;
            comp.SelectPathPanel.FontSize = comp.PanelTitleFontSize;
            comp.SelectPathPanel.TitlePosition = comp.PanelTitlePosition;

            % Hover
            if comp.IsMouseover
                comp.BackgroundPanel.BorderColor = adjustColorBrightness(comp.BorderColor, 0.2);
                comp.SelectPathPanel.BorderColor = adjustColorBrightness(comp.BorderColor, 0.2);
            else
                comp.BackgroundPanel.BorderColor = comp.BorderColor;
                comp.SelectPathPanel.BorderColor = comp.BorderColor;
            end

            % Select UI
            if isempty(comp.SelectedParent)
                comp.SeletedPathTextArea.Value = comp.Placeholder;
                comp.DropdownPanel.Enable = "off";
            else
                comp.SeletedPathTextArea.Value = strjoin(string(comp.SelectedParent), newline);
                comp.DropdownPanel.Enable = "on";
            end


            % Dropdown
            comp.SelectContentLabel.Text = comp.DropDownLabel;
            comp.DropdownItemsCounterLabel.Text = ['(',num2str(size(comp.DropdownItems,1)),')'];
            lastDropdownItem = comp.DropDown.Value;
            if ~isempty(comp.DropdownItems) && size(comp.DropdownItems, 2) == 2
                comp.DropDown.Items = comp.DropdownItems(:,1)';
                comp.DropDown.ItemsData = comp.DropdownItems(:,2)';
                comp.DropdownSettingButton.Enable = "on";
                if ~isequal(comp.DropDown.Value, lastDropdownItem)
                    DropDownValueChanged(comp, []);
                end
            else
                comp.DropDown.Items = {};
                comp.DropDown.ItemsData = {};
                comp.DropdownSettingButton.Enable = "off";
                if ~isequal(comp.DropDown.Value, lastDropdownItem)
                    DropDownValueChanged(comp, []);
                end
            end
            comp.DropDown.Placeholder = comp.DropDownPlaceholder;

            
            % Filter menu
            if comp.DropdownSortAscend
                comp.cm.SortFileNameAsc.Checked = 'on';
                comp.cm.SortFileNameDesc.Checked = 'off';
            else
                comp.cm.SortFileNameAsc.Checked = 'off';
                comp.cm.SortFileNameDesc.Checked = 'on';
            end


            % Responses to main AppFigure
            if comp.AppFigureValid
                % Modify comp.AppFigure properties here to send a response
                % back to the main app/figure

            end


        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 488 216];
            comp.BackgroundColor = [0.9412 0.9412 0.9412];
            comp.CompGridLayout = uigridlayout(comp);
            comp.BackgroundPanel = uipanel(comp.CompGridLayout);
            comp.BackgroundGrid = uigridlayout(comp.BackgroundPanel);
            comp.SelectPathPanel = uipanel(comp.BackgroundGrid);
            comp.SelectPathPanel.Title = 'Select Path';
            comp.GridLayout = uigridlayout(comp.SelectPathPanel);
            comp.SelectPathButton = uibutton(comp.GridLayout, 'push');
            comp.SelectPathButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectPathButtonPushed, true);
            comp.SelectPathButton.Icon = fullfile(pathToMLAPP, 'private', '9104277_folder_filter_sort_data_document_file_search.png');
            comp.SelectPathButton.Text = '';
            comp.SeletedPathTextArea = uitextarea(comp.GridLayout);
            comp.SeletedPathTextArea.Editable = 'off';
            comp.FooterGridLayout = uigridlayout(comp.GridLayout);
            comp.DropdownPanel = uipanel(comp.FooterGridLayout);
            comp.DropdownGridLayout = uigridlayout(comp.DropdownPanel);
            comp.SelectContentLabel = uilabel(comp.DropdownGridLayout);
            comp.SelectContentLabel.Text = 'Select Content';
            comp.DropdownItemsCounterLabel = uilabel(comp.DropdownGridLayout);
            comp.DropdownItemsCounterLabel.Tooltip = {'This is the number of items available in the dropdown menu'};
            comp.DropdownItemsCounterLabel.Text = '(0)';
            comp.RefreshButton = uibutton(comp.DropdownGridLayout, 'push');
            comp.RefreshButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RefreshButtonPushed, true);
            comp.RefreshButton.Icon = fullfile(pathToMLAPP, 'private', '9104128_replay_reload_repeat_rotate.png');
            comp.RefreshButton.Text = '';
            comp.DropdownBarWithSortGridLayout = uigridlayout(comp.DropdownGridLayout);
            comp.DropdownSettingButton = uibutton(comp.DropdownBarWithSortGridLayout, 'push');
            comp.DropdownSettingButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DropdownSettingButtonPushed, true);
            comp.DropdownSettingButton.Icon = fullfile(pathToMLAPP, 'private', '9104236_configuration_sort_setting_filter.png');
            comp.DropdownSettingButton.Text = '';
            comp.DropDown = uidropdown(comp.DropdownBarWithSortGridLayout);
            comp.DropDown.Items = {};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            comp.DropDown.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end