classdef PathSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        CompGridLayout       matlab.ui.container.GridLayout
        BackgroundPanel      matlab.ui.container.Panel
        BackgroundGrid       matlab.ui.container.GridLayout
        SelectPathPanel      matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        FooterGridLayout     matlab.ui.container.GridLayout
        SeletedPathTextArea  matlab.ui.control.TextArea
        SelectPathButton     matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        PathsSelected % A path or paths was selected within this component
    end

    properties (Access = private)
        AppFigureValid (1,1) logical = false; % Whether AppFigure property is a valid Figure
        TooltipText char % Display as tooltip when hover over the file selector dropzone
    end
    
    properties (Access = protected, Constant, Hidden)
        AppLoadLib__internal = import_lib();
    end
    
    properties (Access = public)
        AppFigure % The parent app/figure that this component is a child of
        BorderColor (1,3) double {mustBeReal(BorderColor), mustBeLessThanOrEqual(BorderColor, 1), mustBeGreaterThanOrEqual(BorderColor, 0)} = [0.65 0.65 0.65]; % Border color around the component
        BorderSize (1,1) double {mustBeReal(BorderSize), mustBePositive(BorderSize), mustBeFinite(BorderSize)} = 1; % Border width around the component, set to 0 for no border
        IsMouseover (1,1) logical {mustBeNonempty(IsMouseover)} = false; % Whether the cursor is currently on top of this component, must be properly set by the parent figure/app.
        PanelFontWeight char {mustBeMember(PanelFontWeight, {'normal', 'bold'})} = 'normal'; % Font weight of Panel Text
        PanelTitle char = 'Select Path'; % Set the title of the path selector dropzone box
        PanelTitleFontSize (1,1) double {mustBeReal(PanelTitleFontSize), mustBePositive(PanelTitleFontSize)} = 12; % Font size of Panel Text
        PanelTitlePosition char {mustBeMember(PanelTitlePosition, {'lefttop', 'centertop', 'righttop'})} = 'lefttop'; % Panel Title Text Alignment
        Placeholder {mustBeTextScalar(Placeholder)} = 'Select Path'; % Placeholder text to display when the SelectedPaths result is empty
        SelectMultiple (1,1) logical {mustBeNonempty(SelectMultiple)} = true; % Whether allowing multiple selection
        SelectType char {mustBeMember(SelectType, {'file', 'folder'})} = 'file'; % Which type of path to accept. Either 'file' or 'folder'.
        SelectedPaths % The result of user selection
    end
    
    
    methods (Access = private)
        
        function dnd_ui_dropped(comp, object, data)
            % Get parent figure for focus/alert
            if comp.AppFigureValid
                parentfig = comp.AppFigure;
            else
                parentfig = ancestor(comp.CompGridLayout, 'figure');
            end
            
            selectedPath = filterSelectedPaths(data.names, type=comp.SelectType, allowMultiple=comp.SelectMultiple);
            if ~isempty(selectedPath)
                comp.SelectedPaths = selectedPath;
                notify(comp, 'PathsSelected');
                return
            end

             % Error notification just in case
            type = comp.SelectType;
            switch comp.SelectMultiple
                case true
                    type = ['some ', type, 's'];
                case false
                    type = ['a ', type];
            end
            switch comp.SelectType
                case 'file'
                    uialert(parentfig, ['You must select a valid path to ', type], 'Invalid File Path');
                case 'folder'
                    uialert(parentfig, ['You must select a valid path to ', type], 'Invalid Folder Path');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % Initializing the drag and drop zone
            % MUST wrap in try-catch since opening in AppDesigner will
            % throw an error
            try
                DnD_uifigure(comp.CompGridLayout, @(o,dat) dnd_ui_dropped(comp, o, dat));
            catch
                return
            end
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(comp, event)
            if comp.SelectMultiple; multiselect = 'on'; else; multiselect = 'off'; end

            % Get parent figure for focus/alert
            if comp.AppFigureValid
                parentfig = comp.AppFigure;
            else
                parentfig = ancestor(comp.CompGridLayout, 'figure');
            end

            
            switch comp.SelectType
                case 'file'
                    [file,location] = uigetfile(fullfile(pwd, '*.*'), comp.PanelTitle, comp.PanelTitle, 'MultiSelect', multiselect);
                    if isequal(file,0)
                       selectedPath = {};
                    else
                       selectedPath = fullfile(location,file);
                    end
                case 'folder'
                    selectedPath = uigetdir(pwd, comp.PanelTitle);
                    if isequal(selectedPath,0)
                       selectedPath = {};
                    end
                otherwise
                    return
            end

            selectedPath = cellstr(selectedPath);

            figure(parentfig); % reset the focus to the current app

            if isempty(selectedPath) || any(cellfun(@isempty, selectedPath))
                return
            end

            selectedPath = filterSelectedPaths(selectedPath, type=comp.SelectType, allowMultiple=comp.SelectMultiple);
            
            if ~isempty(selectedPath)
                comp.SelectedPaths = selectedPath';
                notify(comp, 'PathsSelected');
                return
            end


            % Error notification just in case
            switch comp.SelectType
                case 'file'
                    uialert(parentfig, 'You must select a valid path to a file', 'Invalid File Path');
                case 'folder'
                    uialert(parentfig, 'You must select a valid path to a folder', 'Invalid Folder Path');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

            if class(comp.AppFigure) == "matlab.ui.Figure" && isvalid(comp.AppFigure)
                comp.AppFigureValid = true;
            else
                comp.AppFigureValid = false;
            end

            % Border
            comp.BackgroundPanel.BorderColor = comp.BorderColor;
            comp.SelectPathPanel.BorderColor = comp.BorderColor;
            comp.BackgroundPanel.BorderWidth = comp.BorderSize;

            % Panel
            comp.SelectPathPanel.FontWeight = comp.PanelFontWeight;
            comp.SelectPathPanel.Title = comp.PanelTitle;
            comp.SelectPathPanel.FontSize = comp.PanelTitleFontSize;
            comp.SelectPathPanel.TitlePosition = comp.PanelTitlePosition;

            % Hover
            if comp.IsMouseover
                comp.BackgroundPanel.BorderColor = adjustColorBrightness(comp.BorderColor, 0.2);
                comp.SelectPathPanel.BorderColor = adjustColorBrightness(comp.BorderColor, 0.2);
            else
                comp.BackgroundPanel.BorderColor = comp.BorderColor;
                comp.SelectPathPanel.BorderColor = comp.BorderColor;
            end

            % Select UI
            switch comp.SelectType
                case 'file'
                    comp.SelectPathButton.Icon = fullfile('private/9104307_file_data_find_search_document.png');
                case 'folder'
                    comp.SelectPathButton.Icon = fullfile('private/9104277_folder_filter_sort_data_document_file_search.png');
            end
            if isempty(comp.SelectedPaths)
                comp.SeletedPathTextArea.Value = comp.Placeholder;
            else
                comp.SeletedPathTextArea.Value = strjoin(string(comp.SelectedPaths), newline);
            end
            if size(comp.SelectedPaths,1) <= 1
                comp.SeletedPathTextArea.WordWrap = "on";
            else
                comp.SeletedPathTextArea.WordWrap = "off";
            end


            % Tooltip
            type = comp.SelectType;
            switch comp.SelectMultiple
                case true
                    type = [type, 's'];
                case false
                    type = ['a ', type];
            end
            comp.SelectPathPanel.Tooltip = ['Click on the folder icon or drag and drop the ', type, ' within this box to select'];


            % Responses to main AppFigure
            if comp.AppFigureValid
                % Modify comp.AppFigure properties here to send a response
                % back to the main app/figure

            end


        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 488 216];
            comp.BackgroundColor = [0.9412 0.9412 0.9412];
            comp.CompGridLayout = uigridlayout(comp);
            comp.BackgroundPanel = uipanel(comp.CompGridLayout);
            comp.BackgroundGrid = uigridlayout(comp.BackgroundPanel);
            comp.SelectPathPanel = uipanel(comp.BackgroundGrid);
            comp.SelectPathPanel.Title = 'Select Path';
            comp.GridLayout = uigridlayout(comp.SelectPathPanel);
            comp.SelectPathButton = uibutton(comp.GridLayout, 'push');
            comp.SelectPathButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectPathButtonPushed, true);
            comp.SelectPathButton.Icon = fullfile(pathToMLAPP, 'private', '9104277_folder_filter_sort_data_document_file_search.png');
            comp.SelectPathButton.Text = '';
            comp.SeletedPathTextArea = uitextarea(comp.GridLayout);
            comp.SeletedPathTextArea.Editable = 'off';
            comp.FooterGridLayout = uigridlayout(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end