classdef populationDemographics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PopulationDemographicsUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        HowBalancedistheDatasetPanel    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BalancenessInfoLabel            matlab.ui.control.Label
        BalanceRadarUIAxes              matlab.ui.control.UIAxes
        DiversitySummaryPanel           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        AgePanel                        matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        FilterGroupbyPanel              matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        UIAxes3                         matlab.ui.control.UIAxes
        StrainPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, masterMetadataTable)
            screensize = get(0, 'ScreenSize');
            w = 0.7; h = w * 0.85;
            figPos = [(screensize(3)-screensize(3)*w)/2, (screensize(4)-screensize(4)*h)/2, screensize(3)*w, screensize(4)*h];
            app.PopulationDemographicsUIFigure.Position = figPos;


            % Plot the Radar Plot
            graphics.balancePolar(app.BalanceRadarUIAxes, masterMetadataTable, {'ANIMAL_STRAIN', 'ANIMAL_SEX', 'ANIMAL_GENOTYPE'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PopulationDemographicsUIFigure = uifigure('Visible', 'off');
            app.PopulationDemographicsUIFigure.Name = 'Population Demographics';
            app.GridLayout = uigridlayout(app.PopulationDemographicsUIFigure);
            app.StrainPanel = uipanel(app.GridLayout);
            app.StrainPanel.Title = 'Strain';
            app.GridLayout3 = uigridlayout(app.StrainPanel);
            app.AgePanel = uipanel(app.GridLayout);
            app.AgePanel.Title = 'Age';
            app.GridLayout3_2 = uigridlayout(app.AgePanel);
            app.UIAxes3 = uiaxes(app.GridLayout3_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.FilterGroupbyPanel = uipanel(app.GridLayout3_2);
            app.FilterGroupbyPanel.Title = 'Filter / Group by...';
            app.GridLayout5 = uigridlayout(app.FilterGroupbyPanel);
            app.ListBox = uilistbox(app.GridLayout5);
            app.ListBox.Items = {'(All)'};
            app.ListBox.Value = {};
            app.DiversitySummaryPanel = uipanel(app.GridLayout);
            app.DiversitySummaryPanel.Title = 'Diversity Summary';
            app.GridLayout4 = uigridlayout(app.DiversitySummaryPanel);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HowBalancedistheDatasetPanel = uipanel(app.GridLayout);
            app.HowBalancedistheDatasetPanel.Title = 'How Balanced is the Dataset?';
            app.GridLayout2 = uigridlayout(app.HowBalancedistheDatasetPanel);
            app.BalanceRadarUIAxes = uiaxes(app.GridLayout2);
            xlabel(app.BalanceRadarUIAxes, 'X')
            ylabel(app.BalanceRadarUIAxes, 'Y')
            zlabel(app.BalanceRadarUIAxes, 'Z')
            app.BalancenessInfoLabel = uilabel(app.GridLayout2);
            app.BalancenessInfoLabel.Text = {'Here, all combination between Sex, Strain, and Genotype are plotted in a circle. The radius indicates the number of measurements for that combination. The more even or round the plot is, the more balanced the dataset is as a whole.'; ''; 'The data is first grouped by strains, then sex and genotypes.'};
            % Show the figure after all components are created
            app.PopulationDemographicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = populationDemographics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PopulationDemographicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PopulationDemographicsUIFigure)
        end
    end
end